classdef oocheckapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ShowPropertyInspectorButton     matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        CLEfficiencyTab                 matlab.ui.container.Tab
        IntegrationTimesEditField       matlab.ui.control.NumericEditField
        IntegrationTimesEditFieldLabel  matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        CLEffEditField                  matlab.ui.control.NumericEditField
        CLEffEditFieldLabel             matlab.ui.control.Label
        CalculateChemiluminescenceEfficiencyButton  matlab.ui.control.Button
        InputmassofCPPOusedgEditField   matlab.ui.control.NumericEditField
        InputmassofCPPOusedgEditFieldLabel  matlab.ui.control.Label
        InputAbsoluteIrradiancefileButton  matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        SpoolingTab                     matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        ViewTab                         matlab.ui.container.Tab
        ViewButtonGroup                 matlab.ui.container.ButtonGroup
        IntensityvsTimeButton           matlab.ui.control.RadioButton
        IntensityvsWavelengthButton     matlab.ui.control.RadioButton
        DButton                         matlab.ui.control.RadioButton
        XTab                            matlab.ui.container.Tab
        MaxSpinnerLabel                 matlab.ui.control.Label
        XMaxSpinner                     matlab.ui.control.Spinner
        MinSpinnerLabel                 matlab.ui.control.Label
        XMinSpinner                     matlab.ui.control.Spinner
        YTab                            matlab.ui.container.Tab
        MaxSpinner_2Label               matlab.ui.control.Label
        YMaxSpinner                     matlab.ui.control.Spinner
        MinSpinner_2Label               matlab.ui.control.Label
        YMinSpinner                     matlab.ui.control.Spinner
        ZTab                            matlab.ui.container.Tab
        MaxSpinner_3Label               matlab.ui.control.Label
        ZMaxSpinner                     matlab.ui.control.Spinner
        MinSpinner_3Label               matlab.ui.control.Label
        ZMinSpinner                     matlab.ui.control.Spinner
        PreparespoolinggraphButton      matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        c = 2.9978e17 % speed of light in nm/s
        CLEff % in percentage
        denom % denominator for CLEff calculation
        El % energy at specific wavelength in J/nm
        Ephotonl % energy of a photon at specific wavelength in J
        h = 6.626e-34 % planck's constant (Js)
        m % number of rows of data
        mCPPO % mass of CPPO in g
        MCPPO = 677.17 % Molar Mass of CPPO in g/mol
        n % number of columns of data
        NA = 6.02e23 % Avogadro's Number in 1/mol
        nCPPO % moles of CPPO used
        near400 % value nearest to 400 nm
        near700 % value nearest to 700 nm
        Nl % number of photons at each wavelength in 1/nm
        Ntot % total number of photons at each wavelength (unitless)
        p % number of columns of Nl data
        row400 % row number of near400 value
        row700 % row number of near700 value
        spectra % imported spectra, not transposed
        spectraxy % imported spectra, transposed
        sumNtot % total number of photons (unitless)
        wavelength % contains full range of wavelengths
        intT % integration time in s
        xmin % start value for xlim
        xmax % end value for xlim
        ymin % start value for ylim
        ymax % start value for ylim
        zmin % start value for zend
        zmax % start value for zend
        xl % xlims
        yl % ylims
        zl % zlims
    end
    
    methods (Access = private)

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIAxes.FontSize = 12;
            app.UIAxes.LineWidth = 1;
        end

        % Button pushed function: InputAbsoluteIrradiancefileButton
        function InputAbsoluteIrradiancefileButtonPushed(app, event)
            [file,path] = uigetfile("*.txt");
            app.spectra = importdata(strcat(path,file));
            app.spectraxy = transpose(app.spectra); % transpose data so wavelength in first col
            app.wavelength(:,1) = app.spectraxy(:,1);
            app.h = 6.626*10^(-34); % planck's constant (Js)
            app.c = 2.9978 * 10^17; % speed of light (nm/s)
            [app.m,app.n] = size(app.spectraxy); % gather dimensions of data
            for i=1:app.m
              app.Ephotonl(i,1) = (app.h*app.c)./app.wavelength(i,1); % J
            end
            clear i
        end

        % Value changed function: InputmassofCPPOusedgEditField
        function InputmassofCPPOusedgEditFieldValueChanged(app, event)
            app.mCPPO = app.InputmassofCPPOusedgEditField.Value;
            app.MCPPO = 677.17; % g/mol
            app.NA = 6.02 * 10^23; % 1/mol
            app.nCPPO = app.mCPPO./app.MCPPO; % mol
            app.denom = app.nCPPO * app.NA; % unitless
        end

        % Callback function: CLEffEditField, 
        % ...and 1 other component
        function CalculateChemiluminescenceEfficiencyButtonPushed(app, event)
            app.CLEff = app.sumNtot./app.denom * 100; % percentage
            app.CLEffEditField.Value = app.CLEff;
        end

        % Button pushed function: PreparespoolinggraphButton
        function PreparespoolinggraphButtonPushed(app, event)
        x = 1:app.n-1;
        y = app.wavelength;
        z = app.spectraxy(:,2:app.n);
        c = meshgrid(app.spectraxy(1:app.n-1),(1:app.m));
        mesh(app.UIAxes,x,y,z,c,'MeshStyle','Column','LineWidth',1);
        app.UIAxes.YDir = 'reverse';
        xlabel(app.UIAxes,'Time (s)')
        ylabel(app.UIAxes,'Wavelength (nm)')
        zlabel(app.UIAxes,'CL Intensity (cps)')
        app.UIAxes.View = [-50 25];
        app.xl = app.UIAxes.XLim;
        app.yl = app.UIAxes.YLim;
        app.zl = app.UIAxes.ZLim;
        app.XMaxSpinner.Value = double(app.xl(2));
        app.XMinSpinner.Value = double(app.xl(1));
        app.YMaxSpinner.Value = double(app.yl(2));
        app.YMinSpinner.Value = double(app.yl(1));
        app.ZMaxSpinner.Value = double(app.zl(2));
        app.ZMinSpinner.Value = double(app.zl(1));
        end

        % Selection changed function: ViewButtonGroup
        function ViewButtonGroupSelectionChanged(app, event)
            selectedButton = app.ViewButtonGroup.SelectedObject;
            if app.DButton.Value == true
                app.UIAxes.View = [-50 25];
            end
            if app.IntensityvsWavelengthButton.Value == true
                app.UIAxes.View = [-90 0];
            end
            if app.IntensityvsTimeButton.Value == true
                app.UIAxes.View = [0 0];
            end
        end

        % Value changed function: IntegrationTimesEditField
        function IntegrationTimesEditFieldValueChanged(app, event)
            app.intT = app.IntegrationTimesEditField.Value;
            for i=1:app.m
                for j=2:app.n
                    app.El(i,j-1) = app.spectraxy(i,j) * 10^(-6) * double(app.intT); % J/nm
                end
            end
            clear i j
            for i=1:app.m
                for j=2:app.n
                    app.Nl(i,j-1) = app.El(i,j-1)./app.Ephotonl(i,1); % 1/nm
                end
            end
            clear i j
            app.near400 = interp1(app.wavelength,app.wavelength,400,"nearest");
            app.row400 = find(app.wavelength == app.near400);
            app.near700 = interp1(app.wavelength,app.wavelength,700,"nearest");
            app.row700 = find(app.wavelength == app.near700);
            [~,app.p] = size(app.Nl(app.row400:app.row700,:));
            for i=1:app.p
                app.Ntot(:,i) = trapz(app.wavelength(app.row400:app.row700,1),app.Nl(app.row400:app.row700,i)); % unitless
            end
            clear i
            app.sumNtot = sum(app.Ntot); % Total Photons
        end

        % Button pushed function: ShowPropertyInspectorButton
        function ShowPropertyInspectorButtonPushed(app, event)
            inspect(app.UIAxes)
        end

        % Callback function: XMinSpinner, XMinSpinner
        function MinSpinnerValueChanged(app, event)
            app.xmin = app.XMinSpinner.Value;
            app.UIAxes.XLim = [app.xmin app.XMaxSpinner.Value];
        end

        % Callback function: XMaxSpinner, XMaxSpinner
        function XMaxSpinnerValueChanged(app, event)
            app.xmax = app.XMaxSpinner.Value;
            app.UIAxes.XLim = [app.XMinSpinner.Value app.xmax];
        end

        % Callback function: YMaxSpinner, YMaxSpinner
        function YMaxSpinnerValueChanged(app, event)
            app.ymax = app.YMaxSpinner.Value;
            app.UIAxes.YLim = [app.YMinSpinner.Value app.ymax];
        end

        % Callback function: YMinSpinner, YMinSpinner
        function YMinSpinnerValueChanged(app, event)
            app.ymin = app.YMinSpinner.Value;
            app.UIAxes.YLim = [app.ymin app.YMaxSpinner.Value];
        end

        % Callback function: ZMaxSpinner, ZMaxSpinner
        function ZMaxSpinnerValueChanged(app, event)
            app.zmax = app.ZMaxSpinner.Value;
            app.UIAxes.ZLim = [app.ZMinSpinner.Value app.zmax];
        end

        % Callback function: ZMinSpinner, ZMinSpinner
        function ZMinSpinnerValueChanged(app, event)
            app.zmin = app.ZMinSpinner.Value;
            app.UIAxes.ZLim = [app.zmin app.ZMaxSpinner.Value];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CLEfficiencyTab = uitab(app.TabGroup);
            app.CLEfficiencyTab.Title = 'CL Efficiency';
            app.Label = uilabel(app.CLEfficiencyTab);
            app.Label.Text = 'Before beginning, please ensure your data does not contain any headers/text';
            app.InputAbsoluteIrradiancefileButton = uibutton(app.CLEfficiencyTab, 'push');
            app.InputAbsoluteIrradiancefileButton.ButtonPushedFcn = createCallbackFcn(app, @InputAbsoluteIrradiancefileButtonPushed, true);
            app.InputAbsoluteIrradiancefileButton.Text = 'Input "Absolute Irradiance" file';
            app.InputmassofCPPOusedgEditFieldLabel = uilabel(app.CLEfficiencyTab);
            app.InputmassofCPPOusedgEditFieldLabel.Text = 'Input mass of CPPO used (g)';
            app.InputmassofCPPOusedgEditField = uieditfield(app.CLEfficiencyTab, 'numeric');
            app.InputmassofCPPOusedgEditField.ValueChangedFcn = createCallbackFcn(app, @InputmassofCPPOusedgEditFieldValueChanged, true);
            app.CalculateChemiluminescenceEfficiencyButton = uibutton(app.CLEfficiencyTab, 'push');
            app.CalculateChemiluminescenceEfficiencyButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateChemiluminescenceEfficiencyButtonPushed, true);
            app.CalculateChemiluminescenceEfficiencyButton.Text = 'Calculate Chemiluminescence Efficiency';
            app.CLEffEditFieldLabel = uilabel(app.CLEfficiencyTab);
            app.CLEffEditFieldLabel.Text = 'CL Eff =';
            app.CLEffEditField = uieditfield(app.CLEfficiencyTab, 'numeric');
            app.CLEffEditField.ValueChangedFcn = createCallbackFcn(app, @CalculateChemiluminescenceEfficiencyButtonPushed, true);
            app.Label_2 = uilabel(app.CLEfficiencyTab);
            app.Label_2.Text = '%';
            app.IntegrationTimesEditFieldLabel = uilabel(app.CLEfficiencyTab);
            app.IntegrationTimesEditFieldLabel.Text = 'Integration Time (s)';
            app.IntegrationTimesEditField = uieditfield(app.CLEfficiencyTab, 'numeric');
            app.IntegrationTimesEditField.ValueChangedFcn = createCallbackFcn(app, @IntegrationTimesEditFieldValueChanged, true);
            app.SpoolingTab = uitab(app.TabGroup);
            app.SpoolingTab.Title = 'Spooling';
            app.UIAxes = uiaxes(app.SpoolingTab);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.PreparespoolinggraphButton = uibutton(app.SpoolingTab, 'push');
            app.PreparespoolinggraphButton.ButtonPushedFcn = createCallbackFcn(app, @PreparespoolinggraphButtonPushed, true);
            app.PreparespoolinggraphButton.Text = 'Prepare spooling graph';
            app.TabGroup2 = uitabgroup(app.SpoolingTab);
            app.ViewTab = uitab(app.TabGroup2);
            app.ViewTab.Title = 'View';
            app.ViewButtonGroup = uibuttongroup(app.ViewTab);
            app.ViewButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ViewButtonGroupSelectionChanged, true);
            app.ViewButtonGroup.Title = 'View';
            app.DButton = uiradiobutton(app.ViewButtonGroup);
            app.DButton.Text = '3D';
            app.DButton.Value = true;
            app.IntensityvsWavelengthButton = uiradiobutton(app.ViewButtonGroup);
            app.IntensityvsWavelengthButton.Text = 'Intensity vs Wavelength';
            app.IntensityvsTimeButton = uiradiobutton(app.ViewButtonGroup);
            app.IntensityvsTimeButton.Text = 'Intensity vs Time';
            app.XTab = uitab(app.TabGroup2);
            app.XTab.Title = 'X';
            app.XMinSpinner = uispinner(app.XTab);
            app.XMinSpinner.ValueChangingFcn = createCallbackFcn(app, @MinSpinnerValueChanged, true);
            app.XMinSpinner.ValueChangedFcn = createCallbackFcn(app, @MinSpinnerValueChanged, true);
            app.MinSpinnerLabel = uilabel(app.XTab);
            app.MinSpinnerLabel.Text = 'Min';
            app.XMaxSpinner = uispinner(app.XTab);
            app.XMaxSpinner.ValueChangingFcn = createCallbackFcn(app, @XMaxSpinnerValueChanged, true);
            app.XMaxSpinner.ValueChangedFcn = createCallbackFcn(app, @XMaxSpinnerValueChanged, true);
            app.MaxSpinnerLabel = uilabel(app.XTab);
            app.MaxSpinnerLabel.Text = 'Max';
            app.YTab = uitab(app.TabGroup2);
            app.YTab.Title = 'Y';
            app.YMinSpinner = uispinner(app.YTab);
            app.YMinSpinner.ValueChangingFcn = createCallbackFcn(app, @YMinSpinnerValueChanged, true);
            app.YMinSpinner.ValueChangedFcn = createCallbackFcn(app, @YMinSpinnerValueChanged, true);
            app.MinSpinner_2Label = uilabel(app.YTab);
            app.MinSpinner_2Label.Text = 'Min';
            app.YMaxSpinner = uispinner(app.YTab);
            app.YMaxSpinner.ValueChangingFcn = createCallbackFcn(app, @YMaxSpinnerValueChanged, true);
            app.YMaxSpinner.ValueChangedFcn = createCallbackFcn(app, @YMaxSpinnerValueChanged, true);
            app.MaxSpinner_2Label = uilabel(app.YTab);
            app.MaxSpinner_2Label.Text = 'Max';
            app.ZTab = uitab(app.TabGroup2);
            app.ZTab.Title = 'Z';
            app.ZMinSpinner = uispinner(app.ZTab);
            app.ZMinSpinner.ValueChangingFcn = createCallbackFcn(app, @ZMinSpinnerValueChanged, true);
            app.ZMinSpinner.ValueChangedFcn = createCallbackFcn(app, @ZMinSpinnerValueChanged, true);
            app.MinSpinner_3Label = uilabel(app.ZTab);
            app.MinSpinner_3Label.Text = 'Min';
            app.ZMaxSpinner = uispinner(app.ZTab);
            app.ZMaxSpinner.ValueChangingFcn = createCallbackFcn(app, @ZMaxSpinnerValueChanged, true);
            app.ZMaxSpinner.ValueChangedFcn = createCallbackFcn(app, @ZMaxSpinnerValueChanged, true);
            app.MaxSpinner_3Label = uilabel(app.ZTab);
            app.MaxSpinner_3Label.Text = 'Max';
            app.ShowPropertyInspectorButton = uibutton(app.UIFigure, 'push');
            app.ShowPropertyInspectorButton.ButtonPushedFcn = createCallbackFcn(app, @ShowPropertyInspectorButtonPushed, true);
            app.ShowPropertyInspectorButton.Text = 'Show Property Inspector';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = oocheckapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end